classdef ShelfGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ScanButton                    matlab.ui.control.Button
        ExpiredLampLabel              matlab.ui.control.Label
        ExpiredLamp                   matlab.ui.control.Lamp
        NoerrorsLampLabel             matlab.ui.control.Label
        NoerrorsLamp                  matlab.ui.control.Lamp
        LowOutofStockLampLabel        matlab.ui.control.Label
        LowOutofStockLamp             matlab.ui.control.Lamp
        SmartShelfControlCentreLabel  matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        ClickontheitemtofindLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        EPC;
        EPCASCII;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: ScanButton
        function ScanButtonPushed(app, event)
            [app.EPC, app.EPCASCII] = scanShelf();
            app.UITable.Data = [app.EPC' app.EPCASCII'];
            [item, eDates, stockActual, stockFull, expired, lowstock, outofstock] = checkItems(app.EPCASCII);
            
            app.UITable.Data = [app.EPC', app.EPCASCII'];
            %app.UITable.Data = [item' eDates',stockActual',stockFull'];
            
            if sum(expired) == 0 && sum(lowstock) == 0
            app.NoerrorsLamp.Enable = 'on';
            app.LowOutofStockLamp.Enable = 'off';
            app.ExpiredLamp.Enable = 'off';
            elseif sum(expired) > 0 && sum(lowstock) > 0
            app.NoerrorsLamp.Enable = 'off';
            app.LowOutofStockLamp.Enable = 'on';
            app.ExpiredLamp.Enable = 'on';
            elseif sum(expired) > 0
            app.NoerrorsLamp.Enable = 'off';
            app.LowOutofStockLamp.Enable = 'off';
            app.ExpiredLamp.Enable = 'on';
            elseif sum(lowstock) > 0
            app.NoerrorsLamp.Enable = 'off';
            app.LowOutofStockLamp.Enable = 'on';
            app.ExpiredLamp.Enable = 'off';
            else
                error()
            end
          
            
            %checkExpired function
            
%             while(1)
%                 app.ExpiredLamp.Enable = 'on';
%                 pause(0.5);
%                 app.ExpiredLamp.Enable = 'off';
%                 pause(0.5);
%             end
                        
%             while(1)
%                 app.LowOutofStockLamp.Enable = 'on';
%                 pause(1);
%                 app.LowOutofStockLamp.Enable = 'off';
%                 pause(1);
%             end
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            disp(indices);
            disp(app.EPC)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ScanButton = uibutton(app.UIFigure, 'push');
            app.ScanButton.ButtonPushedFcn = createCallbackFcn(app, @ScanButtonPushed, true);
            app.ScanButton.Text = 'Scan';
            app.ExpiredLampLabel = uilabel(app.UIFigure);
            app.ExpiredLampLabel.Text = 'Expired';
            app.ExpiredLamp = uilamp(app.UIFigure);
            app.ExpiredLamp.Enable = 'off';
            app.NoerrorsLampLabel = uilabel(app.UIFigure);
            app.NoerrorsLampLabel.Text = 'No errors';
            app.NoerrorsLamp = uilamp(app.UIFigure);
            app.NoerrorsLamp.Enable = 'off';
            app.LowOutofStockLampLabel = uilabel(app.UIFigure);
            app.LowOutofStockLampLabel.Text = 'Low/Out of Stock';
            app.LowOutofStockLamp = uilamp(app.UIFigure);
            app.LowOutofStockLamp.Enable = 'off';
            app.SmartShelfControlCentreLabel = uilabel(app.UIFigure);
            app.SmartShelfControlCentreLabel.Text = 'Smart Shelf Control Centre';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'EPC'; 'ASCII'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.ClickontheitemtofindLabel = uilabel(app.UIFigure);
            app.ClickontheitemtofindLabel.Text = 'Click on the item to find';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ShelfGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end